classdef SWDapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        WybrkryteriwButton           matlab.ui.control.Button
        WektorWagEditField           matlab.ui.control.EditField
        WektorWagEditFieldLabel      matlab.ui.control.Label
        StwrzrankingButton           matlab.ui.control.Button
        WczytajdanezplikuButton_2    matlab.ui.control.Button
        wybierzmetodeDropDown        matlab.ui.control.DropDown
        wybierzmetodeDropDownLabel   matlab.ui.control.Label
        RankingLabel                 matlab.ui.control.Label
        KlasyLabel                   matlab.ui.control.Label
        AlternatywyzkryteriamiLabel  matlab.ui.control.Label
        UITable3                     matlab.ui.control.Table
        UITable2                     matlab.ui.control.Table
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = public)
        daneA = []; % Description
        daneK = [];
        wektorWag = [0.8 0.1 0.1];
        alternatywy = [];
        dane1 = [];
        dane2 = [];
        options = [];
        selection = [1 2 3];
        pareto_idx = [];
        A0 = [];
        A1 = [];
        A2 = [];
        A3 = [];


    end
    
    methods (Access = public)
        %%%%%%%%%%%%%%%%%%%%%%%%%% RSM %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [result, rank] = RSM(app, U, A0, A1, A2, A3, min_max)
            [P_idx, P] = app.pareto(U, min_max);
            decisions = size(P, 1);     % Number of decisions
            
            % Checking the integrity of reference points sets
            if ~app.check_integrity(A0, min_max)
                disp("No integrity in A0 set")
                app.UITable3.Data = "No integrity in A0 set";
                result = -404;
                return
            end 
            if ~app.check_integrity(A1, min_max)
                disp("No integrity in A1 set")
                app.UITable3.Data = "No integrity in A1 set";
                result = -404;
                return
            end
            if ~app.check_integrity(A2, min_max)
                disp("No integrity in A2 set")
                app.UITable3.Data = "No integrity in A2 set";
                result = -404;
                return
            end
            if ~app.check_integrity(A3, min_max)
                disp("No integrity in A3 set")
                app.UITable3.Data = "No integrity in A3 set";
                result = -404;
                return
            end 
        
            distance0 = zeros(decisions, size(A0, 1));
            distance1 = zeros(decisions, size(A1, 1));
            distance2 = zeros(decisions, size(A2, 1));
            distance3 = zeros(decisions, size(A3, 1));
            
            for i = 1:decisions
                for j = 1:size(A0, 1)
                    distance0(i, j) = norm(P(i, :) - A0(j, :));
                end
                for j = 1:size(A1, 1)
                    distance1(i, j) = norm(P(i, :) - A1(j, :));
                end
                for j = 1:size(A2, 1)
                    distance2(i, j) = norm(P(i, :) - A2(j, :));
                end
                for j = 1:size(A3, 1)
                    distance3(i, j) = norm(P(i, :) - A3(j, :));
                end
            end
               
            scoring_fcn = scoring_function(app, distance0, distance1, decisions);
            rank = sortrows([P, scoring_fcn], 4);
            result = rank(1, 1:size(rank, 2)-1);
            
            figure;
            plot3(A0(:, 1), A0(:, 2), A0(:, 3), "bo-");          % A0
            hold on;
            grid on;
            plot3(A1(:, 1), A1(:, 2), A1(:, 3), "go-");          % A1
            plot3(A2(:, 1), A2(:, 2), A2(:, 3), "co-");          % A2
            plot3(A3(:, 1), A3(:, 2), A3(:, 3), "ro-");          % A3
            plot3(U(:, 1), U(:, 2), U(:, 3), "yo");              % U 
            plot3(P(:, 1), P(:, 2), P(:, 3), "mo");             % Pareto 
            plot3(result(:,1), result(:, 2), result(:, 3), "k*")      % result
            xlabel("X");
            ylabel("Y");
            zlabel("Z");
            legend("A0", "A1", "A2", "A3", "U", "PU", "Best value")
        
        end
        
        function [pareto_idx, pareto_set] = pareto(app, U, min_max)
            % min_max is a array of booleans - true means min, false means max
            % Determining pareto set
            U_size = size(U, 1);
            pareto_set = [];
            pareto_idx = [];
            
            for i = 1:U_size
                is_dominated = false;
                for j = 1:U_size
                    if i ~= j
                        control_sum = 0;
                        for col = 1:size(U, 2);
                            if min_max(col)
                                if U(i, col) >= U(j, col)
                                    % Checking if point is dominated
                                    control_sum = control_sum + 1;
                                end
                            else
                                if U(i, col) <= U(j, col)
                                    % Checking if point is dominated
                                    control_sum = control_sum + 1;
                                end
                            end
                        end
        
                        if control_sum == size(U, 2)
                            is_dominated = true;
                            break;
                        end
                    end
                end
        
                if ~is_dominated
                    pareto_set = [pareto_set; U(i, :)];
                    app.pareto_idx = [app.pareto_idx; i];
                end
            end
        end
        
        function [integrity] = check_integrity(app, class, min_max)
            % The set that is integral must not contain dominated points 
            class_size = size(class, 1);
            integrity = true;
        
            for i = 1:class_size
                for j = 1:class_size
                    if i ~= j
                        control_sum = 0;
                        for col = 1:size(class, 2);
                            if min_max(col)
                                if class(i, col) >= class(j, col)
                                    % Checking if point is dominated
                                    control_sum = control_sum + 1;
                                end
                            else
                                if class(i, col) <= class(j, col)
                                    % Checking if point is dominated
                                    control_sum = control_sum + 1;
                                end
                            end
                        end
        
                        if control_sum == size(class, 2)
                            integrity = false;
                            return
                        end
                    end
                end
            end
        end
        
        function [scoring_result] = scoring_function(app, distance0, distance1, decisions)
            scoring_result = zeros(decisions, 1);
        
            for i = 1:decisions
                mean_A0 = mean(distance0(i, :));
                mean_A1 = mean(distance1(i, :));
                scoring_result(i, 1) = mean([mean_A0, mean_A1]);
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%% SP-CS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [compromisePoint, skoringValue] = SP_CS(app, alternatives, pointsAspiration, pointsStatusQuo)    
            % Krok 2: Usunięcie punktów zdominowanych
            nondominatedAlternatives = app.removeDominatedPoints(alternatives);
            
            % Krok 4: Dodanie zakłóceń
            perturbedAspiration = app.applyUncertainties(pointsAspiration);
            perturbedStatusQuo = app.applyUncertainties(pointsStatusQuo);
            
            % Krok 5: Konstrukcja krzywych szkieletowych
            skeletonCurves = app.constructSkeletonCurves(perturbedAspiration, perturbedStatusQuo);
            app.generatePlot(skeletonCurves, pointsAspiration, pointsStatusQuo)
        
            % Krok 6-7: Znalezienie rzutu punktu F(u) na krzywej łamanej i identyfikacja punktu kompromisowego
            compromisePoint = app.findCompromisePoint(skeletonCurves, nondominatedAlternatives);
        
            % Krok 8: Ocena wartości funckji skoringowej
            skoringValue = app.evaluateScoreFunction(compromisePoint, skeletonCurves);
        
        end
        
        
        % Funkcja usuwa punkty zdominowane
        function nonDominatedPoints = removeDominatedPoints(app, points)
            numPoints = size(points, 1);
            isDominated = false(numPoints, 1);
        
            for i = 1:numPoints
                for j = 1:numPoints
                    if i ~= j
                        % Sprawdzanie czy punkt j dominuje punkt i
                        if all(points(j, :) <= points(i, :)) && any(points(j, :) < points(i, :))
                            isDominated(i) = true;
                            break;
                        end
                    end
                end
            end
            nonDominatedPoints = points(~isDominated, :);
        end
        
        
        % Funckja dodaje zakłóceia
        function perturbedPoints = applyUncertainties(app, points)
            perturbedPoints = points + points .* (0.1 * randn(size(points)));
        end
        
        
        % Funkcja konstruuje krzywe szkieletowe
        function skeletonCurve = constructSkeletonCurves(app, pointsAspiration, pointsStatusQuo)
            aspirationPointsNormalized = pointsAspiration;
            statusQuoPointsNormalized = pointsStatusQuo;
        
            skeletonCurve = [];
        
            for i = 1:min(length(pointsAspiration), length(pointsStatusQuo))
                pointAspiration = aspirationPointsNormalized(i, :);
                pointStatusQuo = statusQuoPointsNormalized(i, :);
        
                interpolatedCurve = app.interpolateLinear(pointStatusQuo, pointAspiration);
                skeletonCurve = [skeletonCurve; interpolatedCurve];
            end
        end
        
        
        % Funkcja normalizuje punkty
        function normalizedPoints = normalizePoints(app, points)
            minVal = min(min(points));
            maxVal = max(max(points));
            normalizedPoints = (points - minVal) / (maxVal - minVal);
        end
        
        
        % Funkcja interpoluje krzywa
        function interpolatedCurve = interpolateLinear(app, point1, point2)
            t = linspace(0, 1, 2);
            interpolatedCurve = point1 + t' * (point2 - point1);
        end
        
        
        % Funckja identyfikuje punkt kompromisowy
        function compromisePoint = findCompromisePoint(app, skeletonCurves, FP_U)
            minScore = inf;
            compromisePoint = [];
            
            for i = 1:size(FP_U, 1)
                t_values = linspace(0, 1, size(skeletonCurves, 1));
                distances = app.chebyshevDistance(skeletonCurves, FP_U(i, :));
                score = sum(t_values + distances);
                
                if score < minScore
                    minScore = score;
                    compromisePoint = FP_U(i, :);
                end
            end
        end
        
        
        % Funkcja oblicza wartosc skoringowa punktu kompromisowego
        function score = evaluateScoreFunction(app, compromisePoint, skeletonCurves)
            t_values = linspace(0, 1, size(skeletonCurves, 1));
            distance = app.chebyshevDistance(skeletonCurves, compromisePoint);
            score = min(sum(t_values + distance));
        end
        
        
        % Funkcja oblicza odleglosc w metryce Czebyszewa
        function distance = chebyshevDistance(app, skeletonCurves, compromisePoint)
            distance = sqrt(sum((skeletonCurves - compromisePoint) .^ 2, 2));
        end
        
        
        % Funkcja rysuje krzywa
        function [] = generatePlot(app, skeletonCurves, pointsAspiration, pointsStatusQuo)
            numPoints = size(skeletonCurves, 1);
            x(1) = skeletonCurves(1, 1);
            y(1) = skeletonCurves(1, 2);
            for i = 2:numPoints
                x(i) = skeletonCurves(i, 1);
                y(i) = skeletonCurves(i, 2);
            end
        
            [sortedX, sortedY] = app.sortPoints(x, y);
        
            figure
            plot(sortedX, sortedY, '-.b', pointsAspiration(:, 1), pointsAspiration(:, 2), '.r', pointsStatusQuo(:, 1), pointsStatusQuo(:, 2), '.g')
            legend("Krzywa", "Punkty Aspiracji", "Punkty StatusQuo", "Location", 'best')
        end
        
        
        % Funckcja sortuje punkty
        function [sortedX, sortedY] = sortPoints(app, x, y)
            sortedX = sort(x);
            sortedY = [];
            for i = 1:length(sortedX)
                valuex = sortedX(i);
                for j = 1:length(sortedX)
                    if valuex == x(j)
                        sortedY(i) = y(j);
                        break
                    end
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajdanezplikuButton_2
        function WczytajdanezplikuButton_2Pushed(app, event)
            [fileName, filePath] = uigetfile({'*.xls', 'Pliki Excel (*.xls)'; '*.*', 'Wszystkie pliki (*.*)'}, 'Wybierz plik Excel');
            
            if isequal(fileName, 0)
                return;
            end
            fullFilePath = fullfile(filePath, fileName);
            figure(app.UIFigure);
            
            app.dane1 = readtable(fullFilePath,'Sheet', 1);
            app.dane2 = readtable(fullFilePath,'Sheet', 2);
            app.A0 = table2array(readtable(fullFilePath,'Sheet', 3));
            app.A1 = table2array(readtable(fullFilePath,'Sheet', 4));
            app.A2 = table2array(readtable(fullFilePath,'Sheet', 5));
            app.A3 = table2array(readtable(fullFilePath,'Sheet', 6));
            app.UITable.Data = app.dane1;
            originalColumnNames1 = app.dane1.Properties.VariableDescriptions;
            app.UITable.ColumnName = originalColumnNames1;

            app.UITable2.Data = app.dane2;
            originalColumnNames2 = app.dane2.Properties.VariableDescriptions;
            app.UITable2.ColumnName = originalColumnNames2;
            
            app.daneA = app.dane1{1:end, 3:end};
            app.daneK = app.dane2{1:end, 2:end};
            app.alternatywy = app.dane1{1:end, 2};
            figure(app.UIFigure)
        end

        % Button pushed function: StwrzrankingButton
        function StwrzrankingButtonPushed(app, event)
            value = app.wybierzmetodeDropDown.Value;
            if value == "TOPSIS"
                [liczbaAlternatyw,~] = size(app.daneA);
                iloscKryteriow = 3;
                
                [liczbaKlas,~] = size(app.daneK);
                [~,iloscWymiarow] = size(app.daneK);

                % sprawdzenie punktow alternatyw
                alternatywyOK = zeros(liczbaAlternatyw, 1);
                for i =1:liczbaAlternatyw
                    for j = 1:3
                        if app.daneA(i,app.selection(j)) >= app.daneK(1,app.selection(j)) && app.daneA(i,app.selection(j)) <= app.daneK(2,app.selection(j))
                            alternatywyOK(i,1) = i;
                        else alternatywyOK(i,1) = 0;
                        end
                    end 
                end
                
                liczbaAlternatywTemp = 0;
                for i =1:liczbaAlternatyw
                    if alternatywyOK(i,1) == 0
                    else liczbaAlternatywTemp = liczbaAlternatywTemp + 1;
                    end
                end
                
                % uzupelnienie macierzy decyzyjnej
                id = 1;
                macierz_decyzyjna = zeros(liczbaAlternatywTemp,iloscKryteriow);
                for i =1:liczbaAlternatyw
                    if alternatywyOK(i,1) ~= 0 
                        for j = 1:3
                            macierz_decyzyjna(id,j) = app.daneA(i,app.selection(j));
                        end
                        id= id+1;
                    end
                end
                liczbaAlternatyw = liczbaAlternatywTemp;
                
                % proces skalowania
                macierz_skalowana = zeros(liczbaAlternatyw,iloscKryteriow);

                for i =1:liczbaAlternatyw
                    for j = 1:3
                        macierz_skalowana(i,j) = (macierz_decyzyjna(i,j) * app.wektorWag(j))/sqrt(sum((double(macierz_decyzyjna(:,j)).^2)));
                    end 
                end
                
                % wyznaczenie wektora idealnego oraz antyidealnego
                %wektorIdealny = [max(macierz_skalowana(:,1)) min(macierz_skalowana(:,2)) min(macierz_skalowana(:,3))];
                %wektorAntyIdealny = [min(macierz_skalowana(:,1)) max(macierz_skalowana(:,2)) max(macierz_skalowana(:,3))];
                
                wektorIdealny = zeros(1,3);
                wektorAntyIdealny = zeros(1,3);

                for i = 1:3
                    if strcmp(app.options{i}, 'max')
                        wektorIdealny(i) = max(macierz_skalowana(:,i));
                        wektorAntyIdealny(i) = min(macierz_skalowana(:,i));
                    elseif strcmp(app.options{i}, 'min')
                        wektorIdealny(i) = min(macierz_skalowana(:,i));
                        wektorAntyIdealny(i) = max(macierz_skalowana(:,i));
                    end
                end

                % wyznaczenie odleglosci w przestrzeni euklidesowej
                odleglosci = zeros(liczbaAlternatyw,2);
                for i =1:liczbaAlternatyw
                    sumaIdealny = 0;
                    sumaAntyIdealny = 0;
                    for j = 1:3
                        sumaIdealny = sumaIdealny+(macierz_skalowana(i,j) - wektorIdealny(j)).^2;
                        sumaAntyIdealny = sumaAntyIdealny+(macierz_skalowana(i,j) - wektorAntyIdealny(j)).^2;
                    end
                 
                    odleglosci(i,1) = sqrt(sumaIdealny);
                    odleglosci(i,2) = sqrt(sumaAntyIdealny);
                end
                
                % uszeregowanie obiektow
                ranking = cell(liczbaAlternatyw,2);
                for i =1:liczbaAlternatyw
                    ranking{i,1} = app.alternatywy(i); 
                    ranking{i,2} = odleglosci(i,2)/(odleglosci(i,1)+odleglosci(i,2));
                end
                ranking_sorted = flip(sortrows(ranking, 2), 1);

                ranking_table = cell2table(ranking_sorted);
                app.UITable3.Data = ranking_table;
                %rysowanie
                figure(1);
                plot3(macierz_decyzyjna(:,1),macierz_decyzyjna(:,2),macierz_decyzyjna(:,3),'+');
                hold on;
                plot3(app.daneK(:,app.selection(1)),app.daneK(:,app.selection(2)),app.daneK(:,app.selection(3)),'o');
                originalColumnNames1 = app.dane1.Properties.VariableDescriptions;
                xlabel(originalColumnNames1(app.selection(1) + 2))
                ylabel(originalColumnNames1(app.selection(2) + 2))
                zlabel(originalColumnNames1(app.selection(3) + 2))
                hold off;
            end


            if value == "RSM"
                v_min_max = [0 0 0];
                for i = 1:3
                    if app.options(i) == "min"
                        v_min_max(i) = 1;
                    else
                        v_min_max(i) = 0;
                    end
                end
                %idx_min = ismember(v_min_max, 'min');
                %idx_max = ismember(v_min_max, 'max');
                
                %v_min_max(idx_min) = num2cell(1);
                %v_min_max(idx_max) = num2cell(0);
                %v_min_max = cell2mat(v_min_max);
                
                % if app.selection(3) == 4
                %     v_min_max = [0 0 1];
                %     disp("jest jest")
                % else
                %     v_min_max = [0 0 0];
                %     disp("o nie")
                % end


                [result, rank] = app.RSM(app.daneA(:, app.selection), app.A0(:, app.selection), app.A1(:, app.selection), app.A2(:, app.selection), app.A3(:, app.selection), v_min_max);
                
                [liczbaAlternatyw,~] = size(rank);
                ranking = cell(liczbaAlternatyw,2);
                for i =1:liczbaAlternatyw
                    ranking{i,1} = app.alternatywy(app.pareto_idx(i)); 
                    ranking{i,2} = rank(i,4);
                end
                ranking_table = cell2table(ranking);
                app.UITable3.Data = ranking_table;


            end


            if value == "SP-CS"
                %Alternatives = abs(randn(20, 2) .* [50, 50]);
                %A = abs(randn(3, 4) .* [35, 35]);
                %SQ = abs(randn(3, 4) .* [65, 65]);

                [compromisePoint, skoringValue] = app.SP_CS(app.daneA(:,1:2), app.A1(:,1:2), app.A2(:,1:2));
                [row, ~] = find(app.daneA(:, 1) == compromisePoint(1) & app.daneA(:, 2) == compromisePoint(2));
                ranking = cell(1,2);
                ranking{1,1} = app.alternatywy(row);
                ranking{1,2} = skoringValue;
                ranking_table = cell2table(ranking);
                app.UITable3.Data = ranking_table;


            end


        end

        % Value changed function: wybierzmetodeDropDown
        function DropDownValueChanged(app, event)
            selectedOption = app.wybierzmetodeDropDown.Value;
            if strcmp(selectedOption, "TOPSIS") || strcmp(selectedOption, "RSM")
                app.WybrkryteriwButton.Visible = 'on';
            else
                app.WybrkryteriwButton.Visible = 'off';
            end

            
            if strcmp(selectedOption, "TOPSIS")
                app.WektorWagEditField.Visible = 'on';
                app.WektorWagEditFieldLabel.Visible = 'on';
            else
                app.WektorWagEditField.Visible = 'off';
                app.WektorWagEditFieldLabel.Visible = 'off';
            end
        end

        % Value changed function: WektorWagEditField
        function EditFieldValueChanged(app, event)
            weights = str2num(app.WektorWagEditField.Value);
            if numel(weights) ~= 3
                warndlg('Wektor musi mieć długość równą 3 (wagi dla trzech kryteriów)', 'Ostrzeżenie');
            else
                app.wektorWag = weights;
            end
        end

        % Button pushed function: WybrkryteriwButton
        function WybrkryteriwButtonPushed(app, event)
            figure(app.UIFigure);
            columnNames = app.dane1.Properties.VariableNames;
            prompt = 'Wybierz trzy kryteria:';
            [app.selection, ok] = listdlg('ListString', columnNames(3:end), 'PromptString', prompt, 'SelectionMode', 'multiple', 'ListSize', [300, 150]);
            
            if ~ok
                return;
            end
            
            if numel(app.selection) ~= 3
                warndlg('Wybierz 3 kryteria', 'Ostrzeżenie');
            end
            

            app.options = cell(1, numel(app.selection));
            for i = 1:numel(app.selection)
                figure(app.UIFigure);
                prompt = sprintf('Wybierz opcję dla kryterium "%s":', columnNames{app.selection(i) + 2});
                result = questdlg(prompt, 'Wybór maksymalizowania/minimalizowania kryterium', 'min', 'max', 'Anuluj');
                if strcmp(result, 'Anuluj')
                    break
                else
                    app.options{i} = result;
                end
            end

            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.AlternatywyzkryteriamiLabel = uilabel(app.UIFigure);
            app.AlternatywyzkryteriamiLabel.Text = 'Alternatywy z kryteriami';
            app.KlasyLabel = uilabel(app.UIFigure);
            app.KlasyLabel.Text = 'Klasy';
            app.RankingLabel = uilabel(app.UIFigure);
            app.RankingLabel.Text = 'Ranking';
            app.wybierzmetodeDropDownLabel = uilabel(app.UIFigure);
            app.wybierzmetodeDropDownLabel.Text = 'wybierz metode';
            app.wybierzmetodeDropDown = uidropdown(app.UIFigure);
            app.wybierzmetodeDropDown.Items = {'TOPSIS', 'RSM', 'SP-CS'};
            app.wybierzmetodeDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.wybierzmetodeDropDown.Value = 'RSM';
            app.WczytajdanezplikuButton_2 = uibutton(app.UIFigure, 'push');
            app.WczytajdanezplikuButton_2.ButtonPushedFcn = createCallbackFcn(app, @WczytajdanezplikuButton_2Pushed, true);
            app.WczytajdanezplikuButton_2.Text = 'Wczytaj dane z pliku';
            app.StwrzrankingButton = uibutton(app.UIFigure, 'push');
            app.StwrzrankingButton.ButtonPushedFcn = createCallbackFcn(app, @StwrzrankingButtonPushed, true);
            app.StwrzrankingButton.Text = 'Stwórz ranking';
            app.WektorWagEditFieldLabel = uilabel(app.UIFigure);
            app.WektorWagEditFieldLabel.Visible = 'off';
            app.WektorWagEditFieldLabel.Text = 'Wektor Wag';
            app.WektorWagEditField = uieditfield(app.UIFigure, 'text');
            app.WektorWagEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.WektorWagEditField.Visible = 'off';
            app.WektorWagEditField.Value = '[0.8 0.1 0.1]';
            app.WybrkryteriwButton = uibutton(app.UIFigure, 'push');
            app.WybrkryteriwButton.ButtonPushedFcn = createCallbackFcn(app, @WybrkryteriwButtonPushed, true);
            app.WybrkryteriwButton.Text = 'Wybór kryteriów';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SWDapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end